classdef DemoApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        StopoverCheckbox     matlab.ui.control.CheckBox
        DestinationDropDown  matlab.ui.control.DropDown
        DestinationLabel     matlab.ui.control.Label
        OriginDropDown       matlab.ui.control.DropDown
        OriginDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        ap = load("airport_database.mat"); % Database of airports
        regionList = {'Africa', 'Asia', 'Europe', 'North America', 'Oceania', 'South America'}; % List of possible regions where airports can be located
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DestinationDropDown, OriginDropDown
        function AirportDropDownValueChanged(app, event)
            value = app.OriginDropDown.Value;
        switch value
            case 'Back to Regions'
                app.OriginDropDown.Items = [{'Select Region'}, app.regionList];
            case app.regionList
                apList = cell(1,height(app.ap.airport_database));
                for k = 1:height(app.ap.airport_database)
                    temp = app.ap.airport_database(k,:);
                    if strcmp(temp.continent, value)
                        apList{k} = temp.name{1};
                    end
                end
                apList = apList(~cellfun('isempty',apList));
                app.OriginDropDown.Items = [{'Select Airport','Back to Regions'}, apList];
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OriginDropDownLabel = uilabel(app.UIFigure);
            app.OriginDropDownLabel.Text = 'Origin';
            app.OriginDropDown = uidropdown(app.UIFigure);
            app.OriginDropDown.Items = {'Select Region', 'Africa', 'Asia', 'Europe', 'North America', 'Oceania', 'South America'};
            app.OriginDropDown.Editable = 'on';
            app.OriginDropDown.ValueChangedFcn = createCallbackFcn(app, @AirportDropDownValueChanged, true);
            app.OriginDropDown.Tooltip = {'Select the airport of origin'};
            app.OriginDropDown.Placeholder = 'Select Region';
            app.OriginDropDown.Value = 'Select Region';
            app.DestinationLabel = uilabel(app.UIFigure);
            app.DestinationLabel.Text = 'Destination';
            app.DestinationDropDown = uidropdown(app.UIFigure);
            app.DestinationDropDown.Items = {'Select Region', 'Africa', 'Asia', 'Europe', 'North America', 'Oceania', 'South America'};
            app.DestinationDropDown.Editable = 'on';
            app.DestinationDropDown.ValueChangedFcn = createCallbackFcn(app, @AirportDropDownValueChanged, true);
            app.DestinationDropDown.Tooltip = {'Select the airport of origin'};
            app.DestinationDropDown.Placeholder = 'Select Region';
            app.DestinationDropDown.Value = 'Select Region';
            app.StopoverCheckbox = uicheckbox(app.UIFigure);
            app.StopoverCheckbox.Text = 'Fly Direct';
            app.StopoverCheckbox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DemoApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end